classdef MainGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FigureWindow                matlab.ui.Figure
        SettingsMenu                matlab.ui.container.Menu
        NumberofvotesperplayerMenu  matlab.ui.container.Menu
        nVotes1Menu                 matlab.ui.container.Menu
        nVotes2Menu                 matlab.ui.container.Menu
        nVotes3Menu                 matlab.ui.container.Menu
        nVotes4Menu                 matlab.ui.container.Menu
        nVotes5Menu                 matlab.ui.container.Menu
        ModifyrestaurantlistMenu    matlab.ui.container.Menu
        VersionMenu                 matlab.ui.container.Menu
        GotoGitHubrepoMenu          matlab.ui.container.Menu
        SubmitabugreportfeaturerequestetcMenu  matlab.ui.container.Menu
        WindowGrid                  matlab.ui.container.GridLayout
        MainGrid                    matlab.ui.container.GridLayout
        GridLayout3                 matlab.ui.container.GridLayout
        RestaurantsGrid             matlab.ui.container.GridLayout
        GridLayout5                 matlab.ui.container.GridLayout
        VotesRemainingLabel         matlab.ui.control.Label
        VotesRemainingValue         matlab.ui.control.Label
        VotingButtonsGrid           matlab.ui.container.GridLayout
        ResetAllVotesButton         matlab.ui.control.Button
        SurpriseMeButton            matlab.ui.control.Button
        ClearMyVotesButton          matlab.ui.control.Button
        VoteButton                  matlab.ui.control.Button
        GridLayout                  matlab.ui.container.GridLayout
        GridLayout2                 matlab.ui.container.GridLayout
        PointerPositionSlider       matlab.ui.control.Slider
        PointerPositionSliderLabel  matlab.ui.control.Label
        SpinButton                  matlab.ui.control.Button
        WheelAxes                   matlab.ui.control.UIAxes
    end

    %% Properties -- data
    properties (Access = private)
        % Restaurant objects loaded in the app.
        restaurants (1,:) WheelOfFortuneCookies.Restaurant.Restaurant

        % Handle of "the wheel".
        wheel WheelOfFortuneCookies.SpinnerWheel {mustBeScalarOrEmpty}

        % Handles of all restaurant controls that are dynamically created. These are used to more
        % easily enable and disable all of the restaurant controls.
        RestaurantControls (1,:)

        % Number of votes that each player gets. This counts both the "add" and "remove" votes.
        nVotesPerPlayer (1,1) double...
            {mustBePositive,mustBeInteger,mustBeLessThanOrEqual(nVotesPerPlayer,5)} = 2
    end

    %% Properties -- voting
    properties (Dependent, Access = private)
        % Number of restaurants that have been added to the app.
        nRestaurants (1,1) double

        % Total number of votes that have been cast.
        nVotesTotal (1,1) double

        % Total number of votes that are waiting to be cast. This counts both the "add" and "remove"
        % votes.
        nVotesTempTotal (1,1) double
    end

    %% Properties -- versioning
    properties (Constant, Access = private)
        % Version of the app. Right now this is only used as a visual indicator -- nothing actually
        % reads or processes this number.
        versionNumber = "2022.12"

        % URL of the GitHub repo where the most recent version of the code (any any development
        % versions, issues, etc.) can be found.
        githubRepoLink = "https://github.com/ThatMechEGuy/WheelOfFortuneCookies"

        % Direct link to creating a new "issue" for the project (bug reports, feature requests,
        % etc.).
        githubIssuesLink = "https://github.com/ThatMechEGuy/WheelOfFortuneCookies/issues/new"
    end

    %% Properties -- child GUIs
    properties (Access = private)
        % Handle of the restaurant management GUI.
        restaurantGUI WheelOfFortuneCookies.Restaurant.ManagmentGUI

        % Handle of the "winner screen" GUI.
        winnerGUI WheelOfFortuneCookies.WinnerMessageGUI

        % Listener to detect when the restraurant management GUI is closed.
        elRestaurantGUIclosed event.listener
    end

    %% Properties -- general
    properties (Access = private)
        progressBar matlab.ui.dialog.ProgressDialog
    end

    %% Methods -- set/get
    methods
        function out = get.nRestaurants(app)
            out = numel(app.restaurants);
        end

        function out = get.nVotesTotal(app)
            out = sum([app.restaurants.nVotes]);
        end

        function out = get.nVotesTempTotal(app)
            out = sum(abs([app.restaurants.nVotesTemp]));
        end
    end
    
    %% Methods -- restaurant controls
    methods (Access = private)
        function addRestaurant(app,nameAndLogo)
            arguments
                app (1,1) WheelOfFortuneCookies.MainGUI
                nameAndLogo (:,:) string
            end

            for ii = 1:height(nameAndLogo)
                if nameAndLogo(ii,2) ~= ""
                    fileName = fullfile(WheelOfFortuneCookies.HelperFunctions.installationLocation,"+WheelOfFortuneCookies","+Restaurant","Defaults","Logo Images",nameAndLogo(ii,2));
                else
                    fileName = string.empty;
                end
                app.restaurants(app.nRestaurants+1) = WheelOfFortuneCookies.Restaurant.Restaurant(name=nameAndLogo(ii,1),logoImageFilename=fileName);
            end
        end

        function addRestaurantControls(app)
             RG = app.RestaurantsGrid;
             RG.RowHeight = repmat({'fit'},[1,5]);
             RG.ColumnWidth = repmat({'1x'},[1,3]);

             for ii = 1:app.nRestaurants
                 rCell = uigridlayout(RG,[2,1],Padding=0);

                 rCell.RowHeight = {'fit','fit'};
    
                 controlsGrid = uigridlayout(rCell,[1,3],Padding=0);
                 controlsGrid.Layout.Row = 2;
    
                 L = uilabel(rCell,Text=app.restaurants(ii).name,HorizontalAlignment='Center',FontSize=18);
                 L.Layout.Row = 1;
                 
                 RB = uibutton(controlsGrid,'push',Text="-",FontSize=24,ButtonPushedFcn=@(~,~)app.addRemoveVoteButtonClickedCallback(app.restaurants(ii),-1));
                 EF = uieditfield(controlsGrid,'Text',Value="0",FontSize=18,HorizontalAlignment='center');
                 AB = uibutton(controlsGrid,'push',Text="+",FontSize=24,ButtonPushedFcn=@(~,~)app.addRemoveVoteButtonClickedCallback(app.restaurants(ii),1));

                 UD.RemoveButton = RB;
                 UD.AddButton = AB;
                 UD.EditField = EF;
                 UD.Label = L;

                 app.RestaurantControls(end+1) = RB;
                 app.RestaurantControls(end+1) = AB;
                 app.RestaurantControls(end+1) = EF;
                 app.RestaurantControls(end+1) = L;

                 app.restaurants(ii).UserData = UD;
             end
        end

        function addRemoveVoteButtonClickedCallback(app,R,pm)

            R.nVotesTemp = R.nVotesTemp + pm;

            switch sign(R.nVotesTemp)
                case 1
                    textColor = [0,0.5,0];
                    signChar = '+';
                    backgroundColor = [0.8,1,0.8];
                case 0
                    textColor = 'k';
                    signChar = '';
                    backgroundColor = 'w';
                case -1
                    textColor = 'r';
                    signChar = '';
                    backgroundColor = [1,0.8,0.8];
            end

            R.UserData.EditField.Value = sprintf('%s%i',signChar,R.nVotesTemp);
            R.UserData.EditField.FontColor = textColor;
            R.UserData.EditField.BackgroundColor = backgroundColor;


            app.updateVoteButtonsEnabledState;
            app.VotesRemainingValue.Text = num2str(app.nVotesPerPlayer - app.nVotesTempTotal);
        end

        function restaurantGUIclosing(app,src,evt)
            
            delete(app.elRestaurantGUIclosed)
            delete(app.progressBar)
        end
    end

    %% Methods -- vote counting
    methods (Access = private)
        function updateVoteButtonsEnabledState(app)
            app.VoteButton.Enable = app.nVotesTempTotal == app.nVotesPerPlayer;
            app.SurpriseMeButton.Enable = app.nVotesTempTotal < app.nVotesPerPlayer;
            app.ClearMyVotesButton.Enable = app.nVotesTempTotal > 0;
            app.ResetAllVotesButton.Enable = app.nVotesTotal > 0;

            app.PointerPositionSlider.Enable = app.nVotesTotal > 0;
            app.SpinButton.Enable = app.nVotesTotal > 0;

            for R = app.restaurants
                if app.nVotesTempTotal < app.nVotesPerPlayer
                    R.UserData.AddButton.Enable = true;
                    R.UserData.RemoveButton.Enable = R.nVotesTemp > 0 || abs(R.nVotesTemp) < R.nVotes;
                    R.UserData.EditField.Enable = true;
                    R.UserData.Label.Enable = true;
                else
                    R.UserData.AddButton.Enable = R.nVotesTemp < 0;
                    R.UserData.RemoveButton.Enable = R.nVotesTemp > 0;
                    R.UserData.EditField.Enable = R.nVotesTemp ~= 0;
                    R.UserData.Label.Enable = R.nVotesTemp ~= 0;
                end
            end
        end

        function resetAllVotes(app)
            hasTempVotes = abs([app.restaurants.nVotesTemp])>0;
            hasVotes = [app.restaurants.nVotes]>0;

            for R = app.restaurants(hasVotes | hasTempVotes)
                R.resetVotes;
                app.addRemoveVoteButtonClickedCallback(R,0);
            end
            app.drawWheel;
        end
    end

    %% Methods -- the wheel
    methods (Access = private)
        function drawWheel(app)
            arguments
                app (1,1) WheelOfFortuneCookies.MainGUI
            end

            app.wheel.draw([app.restaurants.nVotes],[app.restaurants.name],app.restaurants);
        end
    end

    methods (Static, Access = private)
        function updateVoteCounts(e,EF)
            R = e.AffectedObject;

            EF.Value = num2str(R.nVotes);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.VersionMenu.Text = sprintf('Version %s',app.versionNumber);
            
            app.addRestaurant(["Afro Deli","Afro Deli Logo.png";
                "Blaze Pizza","Blaze Pizza Logo.jpg";
                "Burger King","Burger King Logo.png";
                "Cane's","";
                "Chipotle","";
                "D.P. Dough","";
                "Five Guys","";
                "Jimmy Johns","";
                "McDonald's","";
                "Mesa Pizza","";
                "My Burger","";
                "Naf Naf Grill","";
                "Noodles & Co.","";
                "Potbelly","";
                "Punch Pizza","";
                "QDOBA","";
                "Subway","";
                "Wally's",""]);

            app.addRestaurantControls;
            app.updateVoteButtonsEnabledState;
            try
                app.(sprintf('nVotes%iMenu',app.nVotesPerPlayer)).Checked = true;
            catch
                app.nVotes2Menu.Checked = true;
                app.nVotesPerPlayer = 2;
            end
            app.VotesRemainingValue.Text = num2str(app.nVotesPerPlayer);

            app.wheel = WheelOfFortuneCookies.SpinnerWheel(app.WheelAxes);
            app.drawWheel;

            movegui(app.FigureWindow,"center");
        end

        % Button pushed function: SpinButton
        function SpinButtonPushed(app, event)
            delete(app.winnerGUI)

            if app.VoteButton.Enable == true
                app.VoteButtonPushed;
            end
            

            set(app.RestaurantControls,'Enable',false);

            app.ResetAllVotesButton.Enable = false;
            app.ClearMyVotesButton.Enable = false;
            app.SurpriseMeButton.Enable = false;
            app.VoteButton.Enable = false;
            app.VotesRemainingLabel.Enable = false;
            app.VotesRemainingValue.Enable = false;
            set(app.SettingsMenu.Children,'Enable',false);
            
            app.SpinButton.Enable = false;
            app.PointerPositionSlider.Enable = false;

            [~,WinnerRestaurant] = app.wheel.spin;

            %{
            • Return the winner as the handle of the restaurant
            %}

            if ~isempty(WinnerRestaurant)
                app.winnerGUI = WheelOfFortuneCookies.WinnerMessageGUI(WinnerRestaurant.name,WinnerRestaurant.logoImageFilename);
                posChild = app.winnerGUI.FigureWindow.Position;
                posSelf = app.FigureWindow.Position;

                widthChild = posChild(3);
                heightChild = posChild(4);
                xSelf = posSelf(1);
                ySelf = posSelf(2);
                widthSelf = posSelf(3);
                heightSelf = posSelf(4);

                app.winnerGUI.FigureWindow.Position(1:2) = [xSelf+(widthSelf-widthChild)/2,ySelf+(heightSelf-heightChild)/2];
            else
                uialert(app.FigureWindow,sprintf(['Wow, you sure are lucky! You somehow managed to ',...
                    'land exactly between two restaurants.\n\nSend a picture of this message to Nate ',...
                    'to impress him, then spin again to find a winner.']),'Tie',Icon='error');
            end

            app.PointerPositionSlider.Enable = true;
            app.SpinButton.Enable = true;

            app.updateVoteButtonsEnabledState;
            app.VotesRemainingLabel.Enable = true;
            app.VotesRemainingValue.Enable = true;
            set(app.SettingsMenu.Children,'Enable',true);
        end

        % Button pushed function: VoteButton
        function VoteButtonPushed(app, event)
            hasTempVotes = abs([app.restaurants.nVotesTemp])>0;
            
            for R = app.restaurants(hasTempVotes)
                R.countTempVotes;
                app.addRemoveVoteButtonClickedCallback(R,0);
            end
            app.drawWheel;
        end

        % Button pushed function: ResetAllVotesButton
        function ResetAllVotesButtonPushed(app, event)
            % Prompt the user.
            dlgAns = uiconfirm(app.FigureWindow,...
                'Are you sure you''d like to reset all of the votes?',...
                'Confirm Reset','Options',{'Yes','No'},'DefaultOption','No');
            if strcmp(dlgAns,'No')
                return
            end
            
            app.resetAllVotes;
        end

        % Button pushed function: ClearMyVotesButton
        function ClearMyVotesButtonPushed(app, event)
            hasTempVotes = abs([app.restaurants.nVotesTemp])>0;

            for R = app.restaurants(hasTempVotes)
                R.resetVotes(tempVotesOnly=true);
                app.addRemoveVoteButtonClickedCallback(R,0);
            end
        end

        % Button pushed function: SurpriseMeButton
        function SurpriseMeButtonPushed(app, event)
            alreadySelectedInds = find(abs([app.restaurants.nVotesTemp])>0);
            
            while app.nVotesTempTotal < app.nVotesPerPlayer
                rInd = randi(app.nRestaurants);

                if any(rInd == alreadySelectedInds)
                    continue
                end

                
                R = app.restaurants(rInd);
                addVal = rand > 0.5;
                if -R.nVotesTemp < R.nVotes && ~addVal
                    app.addRemoveVoteButtonClickedCallback(R,-1);
                else
                    app.addRemoveVoteButtonClickedCallback(R,1);
                end
            end

        end

        % Value changing function: PointerPositionSlider
        function PointerPositionSliderValueChanging(app, event)
            app.wheel.drawPointer(event.Value);
        end

        % Menu selected function: nVotes1Menu, nVotes2Menu, nVotes3Menu, 
        % ...and 2 other components
        function nVotesMenuSelected(app, event)
            % Get the handle of the menu option that was just selected.
            mnu = event.Source;

            if mnu.Checked
                return
            end

            if app.ResetAllVotesButton.Enable == true || app.nVotesTempTotal > 0
                dlgAns = uiconfirm(app.FigureWindow,...
                    "Changing the number of votes per player will reset all current votes. Continue?",...
                    "Confirm Changing Number of Votes",Options={'Yes','No'},DefaultOption='No');
                if strcmp(dlgAns,'No')
                    return
                end
            end

            app.resetAllVotes;


            mnu.Checked = true;
            

            % Go through all other items in the current list and uncheck them.
            for C = mnu.Parent.Children.'
                if C ~= mnu
                    C.Checked = false;
                end
            end

            app.nVotesPerPlayer = str2double(mnu.Text);
            app.VotesRemainingValue.Text = num2str(app.nVotesPerPlayer);
        end

        % Menu selected function: GotoGitHubrepoMenu
        function GotoGitHubrepoMenuSelected(app, event)
            web(app.githubRepoLink);
        end

        % Menu selected function: SubmitabugreportfeaturerequestetcMenu
        function SubmitabugreportfeaturerequestetcMenuSelected(app, event)
            web(app.githubIssuesLink);
        end

        % Menu selected function: ModifyrestaurantlistMenu
        function ModifyrestaurantlistMenuSelected(app, event)
            app.progressBar = uiprogressdlg(app.FigureWindow,Indeterminate=true,...
                title='Modifying Restaurant List',message=['The restaurant list modification ',...
                'GUI is open. Close it to continue using this window.']);

            app.restaurantGUI = WheelOfFortuneCookies.Restaurant.ManagmentGUI(app,app.restaurants);

            app.elRestaurantGUIclosed = listener(app.restaurantGUI,'ObjectBeingDestroyed',...
                @app.restaurantGUIclosing);
        end

        % Close request function: FigureWindow
        function FigureWindowCloseRequest(app, event)
            try
                delete(app.restaurantGUI)
                delete(app.winnerGUI)
                delete(app)
            catch
                delete(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FigureWindow = uifigure('Visible', 'off');
            app.FigureWindow.Name = 'Wheel of Fortune Cookies';
            app.FigureWindow.CloseRequestFcn = createCallbackFcn(app, @FigureWindowCloseRequest, true);
            app.SettingsMenu = uimenu(app.FigureWindow);
            app.SettingsMenu.Text = 'Settings';
            app.NumberofvotesperplayerMenu = uimenu(app.SettingsMenu);
            app.NumberofvotesperplayerMenu.Text = 'Number of votes per player';
            app.nVotes1Menu = uimenu(app.NumberofvotesperplayerMenu);
            app.nVotes1Menu.MenuSelectedFcn = createCallbackFcn(app, @nVotesMenuSelected, true);
            app.nVotes1Menu.Text = '1';
            app.nVotes2Menu = uimenu(app.NumberofvotesperplayerMenu);
            app.nVotes2Menu.MenuSelectedFcn = createCallbackFcn(app, @nVotesMenuSelected, true);
            app.nVotes2Menu.Text = '2';
            app.nVotes3Menu = uimenu(app.NumberofvotesperplayerMenu);
            app.nVotes3Menu.MenuSelectedFcn = createCallbackFcn(app, @nVotesMenuSelected, true);
            app.nVotes3Menu.Text = '3';
            app.nVotes4Menu = uimenu(app.NumberofvotesperplayerMenu);
            app.nVotes4Menu.MenuSelectedFcn = createCallbackFcn(app, @nVotesMenuSelected, true);
            app.nVotes4Menu.Text = '4';
            app.nVotes5Menu = uimenu(app.NumberofvotesperplayerMenu);
            app.nVotes5Menu.MenuSelectedFcn = createCallbackFcn(app, @nVotesMenuSelected, true);
            app.nVotes5Menu.Text = '5';
            app.ModifyrestaurantlistMenu = uimenu(app.SettingsMenu);
            app.ModifyrestaurantlistMenu.MenuSelectedFcn = createCallbackFcn(app, @ModifyrestaurantlistMenuSelected, true);
            app.ModifyrestaurantlistMenu.Text = 'Modify restaurant list...';
            app.VersionMenu = uimenu(app.FigureWindow);
            app.VersionMenu.Text = 'Version ####.##';
            app.GotoGitHubrepoMenu = uimenu(app.VersionMenu);
            app.GotoGitHubrepoMenu.MenuSelectedFcn = createCallbackFcn(app, @GotoGitHubrepoMenuSelected, true);
            app.GotoGitHubrepoMenu.Text = 'Go to GitHub repo...';
            app.SubmitabugreportfeaturerequestetcMenu = uimenu(app.VersionMenu);
            app.SubmitabugreportfeaturerequestetcMenu.MenuSelectedFcn = createCallbackFcn(app, @SubmitabugreportfeaturerequestetcMenuSelected, true);
            app.SubmitabugreportfeaturerequestetcMenu.Text = 'Submit a bug report/feature request/etc. ...';
            app.WindowGrid = uigridlayout(app.FigureWindow);
            app.MainGrid = uigridlayout(app.WindowGrid);
            app.GridLayout = uigridlayout(app.MainGrid);
            app.WheelAxes = uiaxes(app.GridLayout);
            title(app.WheelAxes, 'Title')
            xlabel(app.WheelAxes, 'X')
            ylabel(app.WheelAxes, 'Y')
            zlabel(app.WheelAxes, 'Z')
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.SpinButton = uibutton(app.GridLayout2, 'push');
            app.SpinButton.ButtonPushedFcn = createCallbackFcn(app, @SpinButtonPushed, true);
            app.SpinButton.Text = 'Spin';
            app.PointerPositionSliderLabel = uilabel(app.GridLayout2);
            app.PointerPositionSliderLabel.Text = 'Pointer Position';
            app.PointerPositionSlider = uislider(app.GridLayout2);
            app.PointerPositionSlider.Limits = [0 360];
            app.PointerPositionSlider.MajorTicks = [];
            app.PointerPositionSlider.MajorTickLabels = {''};
            app.PointerPositionSlider.ValueChangingFcn = createCallbackFcn(app, @PointerPositionSliderValueChanging, true);
            app.PointerPositionSlider.MinorTicks = [];
            app.PointerPositionSlider.Value = 180;
            app.GridLayout3 = uigridlayout(app.MainGrid);
            app.VotingButtonsGrid = uigridlayout(app.GridLayout3);
            app.VoteButton = uibutton(app.VotingButtonsGrid, 'push');
            app.VoteButton.ButtonPushedFcn = createCallbackFcn(app, @VoteButtonPushed, true);
            app.VoteButton.Text = 'Vote';
            app.ClearMyVotesButton = uibutton(app.VotingButtonsGrid, 'push');
            app.ClearMyVotesButton.ButtonPushedFcn = createCallbackFcn(app, @ClearMyVotesButtonPushed, true);
            app.ClearMyVotesButton.Text = 'Clear My Votes';
            app.SurpriseMeButton = uibutton(app.VotingButtonsGrid, 'push');
            app.SurpriseMeButton.ButtonPushedFcn = createCallbackFcn(app, @SurpriseMeButtonPushed, true);
            app.SurpriseMeButton.Text = 'Surprise Me';
            app.ResetAllVotesButton = uibutton(app.VotingButtonsGrid, 'push');
            app.ResetAllVotesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAllVotesButtonPushed, true);
            app.ResetAllVotesButton.Text = 'Reset All Votes';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.VotesRemainingValue = uilabel(app.GridLayout5);
            app.VotesRemainingValue.Text = '5';
            app.VotesRemainingLabel = uilabel(app.GridLayout5);
            app.VotesRemainingLabel.Text = 'Votes Remaining:';
            app.RestaurantsGrid = uigridlayout(app.GridLayout3);
            % Show the figure after all components are created
            app.FigureWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FigureWindow)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FigureWindow)
        end
    end
end