classdef WheelOfFortuneCookiesGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FigureWindow                matlab.ui.Figure
        WindowGrid                  matlab.ui.container.GridLayout
        MainGrid                    matlab.ui.container.GridLayout
        GridLayout                  matlab.ui.container.GridLayout
        GridLayout2                 matlab.ui.container.GridLayout
        PointerPositionSlider       matlab.ui.control.Slider
        PointerPositionSliderLabel  matlab.ui.control.Label
        SpinButton                  matlab.ui.control.Button
        WheelAxes                   matlab.ui.control.UIAxes
        RestaurantsGrid             matlab.ui.container.GridLayout
        BottomControlsGrid          matlab.ui.container.GridLayout
        VersionHyperlink            matlab.ui.control.Hyperlink
        CloseButton                 matlab.ui.control.Button
        VersionPrefixLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        Restaurants (1,:) restaurant
        Wheel spinnerWheel {mustBeScalarOrEmpty}
    end

    properties (Dependent, Access = private)
        nRestaurants (1,1) double
    end

    properties (Constant, Access = private)
        nPtsCirc = 360
        nVotes = 2
    end

    methods
        function out = get.nRestaurants(app)
            out = numel(app.Restaurants);
        end
    end
    
    methods (Access = private)
        function addRestaurant(app,name)
            arguments
                app (1,1) WheelOfFortuneCookiesGUI
                name (1,:) string
            end

            for ii = 1:numel(name)
                app.Restaurants(app.nRestaurants+1) = restaurant(name=name(ii));
            end
        end

        function drawWheel(app,pointerPosition)
            arguments
                app
                pointerPosition = -app.PointerPositionSlider.Value;
            end

            app.Wheel.draw([app.Restaurants.nVotes],[app.Restaurants.name],pointerPosition);
            
            
        end

        function addRestaurantControls(app)
             RG = app.RestaurantsGrid;
             RG.RowHeight = repmat({'fit'},[1,5]);
             RG.ColumnWidth = repmat({'1x'},[1,3]);

             for ii = 1:app.nRestaurants
                 rCell = uigridlayout(RG,[2,1],Padding=0);

                 rCell.RowHeight = {'fit','fit'};
    
                 controlsGrid = uigridlayout(rCell,[1,3],Padding=0);
                 controlsGrid.Layout.Row = 2;
    
                 L = uilabel(rCell,Text=app.Restaurants(ii).name,HorizontalAlignment='Center',FontSize=18);
                 L.Layout.Row = 1;
                 UD.Restaurant = app.Restaurants(ii);
                 UD.type = 'remove';
                 uibutton(controlsGrid,'push',Text="-",FontSize=24,ButtonPushedFcn=@app.buttonClickedCallback,UserData=UD);
                 uieditfield(controlsGrid,'Text',Value="0",FontSize=18,HorizontalAlignment='center');
                 UD.type = 'add';
                 uibutton(controlsGrid,'push',Text="+",FontSize=24,ButtonPushedFcn=@app.buttonClickedCallback,UserData=UD);
             end
        end

        function buttonClickedCallback(app,src,evt)
            UD = src.UserData;

            switch UD.type
                case 'add'
                    UD.Restaurant.addVote;
                case 'remove'
                    UD.Restaurant.removeVote;
            end

            app.drawWheel;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.addRestaurant(["Afro Deli";
                "Blaze Pizza";
                "Burger King";
                "Cane's";
                "Chipotle";
                "D.P. Dough";
                "Five Guys";
                "Jimmy Johns";
                "McDonald's";
                "Mesa Pizza";
                "My Burger";
                "Naf Naf Grill";
                "Noodles & Co.";
                "Potbelly";
                "Punch Pizza";
                "QDOBA";
                "Subway";
                "Wally's"]);

            app.addRestaurantControls;

            app.Wheel = spinnerWheel(app.WheelAxes);
            app.drawWheel;
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: SpinButton
        function SpinButtonPushed(app, event)
            app.SpinButton.Enable = false;
            app.PointerPositionSlider.Enable = false;
            

            % • Winner needs to account for pointer position

            winner = app.Wheel.spin;

            app.PointerPositionSlider.Enable = true;
            app.SpinButton.Enable = true;

            uialert(app.FigureWindow,sprintf('%s is the winner!',winner),'Winner');
        end

        % Value changed function: PointerPositionSlider
        function PointerPositionSliderValueChanged(app, event)
            app.drawWheel;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FigureWindow = uifigure('Visible', 'off');
            app.FigureWindow.Name = 'Wheel of Fortune Cookies';
            app.WindowGrid = uigridlayout(app.FigureWindow);
            app.BottomControlsGrid = uigridlayout(app.WindowGrid);
            app.VersionPrefixLabel = uilabel(app.BottomControlsGrid);
            app.VersionPrefixLabel.Text = 'Version:';
            app.CloseButton = uibutton(app.BottomControlsGrid, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.VersionHyperlink = uihyperlink(app.BottomControlsGrid);
            app.VersionHyperlink.Tooltip = {'Click here to go to the GitHub repository with the newest version.'};
            app.VersionHyperlink.URL = 'https://github.com/ThatMechEGuy/WheelOfFortuneCookies';
            app.VersionHyperlink.Text = '2022.12';
            app.MainGrid = uigridlayout(app.WindowGrid);
            app.RestaurantsGrid = uigridlayout(app.MainGrid);
            app.GridLayout = uigridlayout(app.MainGrid);
            app.WheelAxes = uiaxes(app.GridLayout);
            title(app.WheelAxes, 'Title')
            xlabel(app.WheelAxes, 'X')
            ylabel(app.WheelAxes, 'Y')
            zlabel(app.WheelAxes, 'Z')
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.SpinButton = uibutton(app.GridLayout2, 'push');
            app.SpinButton.ButtonPushedFcn = createCallbackFcn(app, @SpinButtonPushed, true);
            app.SpinButton.Text = 'Spin';
            app.PointerPositionSliderLabel = uilabel(app.GridLayout2);
            app.PointerPositionSliderLabel.Text = 'Pointer Position';
            app.PointerPositionSlider = uislider(app.GridLayout2);
            app.PointerPositionSlider.Limits = [0 360];
            app.PointerPositionSlider.MajorTicks = [];
            app.PointerPositionSlider.MajorTickLabels = {''};
            app.PointerPositionSlider.ValueChangedFcn = createCallbackFcn(app, @PointerPositionSliderValueChanged, true);
            app.PointerPositionSlider.MinorTicks = [];
            app.PointerPositionSlider.Value = 180;
            % Show the figure after all components are created
            app.FigureWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WheelOfFortuneCookiesGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FigureWindow)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FigureWindow)
        end
    end
end